classdef toruqeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LIactuatortorquecomputeUIFigure  matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        pSliderLabel                    matlab.ui.control.Label
        pSlider                         matlab.ui.control.Slider
        ODSliderLabel                   matlab.ui.control.Label
        ODSlider                        matlab.ui.control.Slider
        heightSliderLabel               matlab.ui.control.Label
        heightSlider                    matlab.ui.control.Slider
        widthSliderLabel                matlab.ui.control.Label
        widthSlider                     matlab.ui.control.Slider
        CvSliderLabel                   matlab.ui.control.Label
        CvSlider                        matlab.ui.control.Slider
        minEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        CvNmEditFieldLabel              matlab.ui.control.Label
        CvNmEditField                   matlab.ui.control.EditField
        ODcmEditFieldLabel              matlab.ui.control.Label
        ODcmEditField                   matlab.ui.control.EditField
        pmmroundEditFieldLabel          matlab.ui.control.Label
        pmmroundEditField               matlab.ui.control.EditField
        heightcmEditFieldLabel          matlab.ui.control.Label
        heightcmEditField               matlab.ui.control.EditField
        widthcmEditFieldLabel           matlab.ui.control.Label
        widthcmEditField                matlab.ui.control.EditField
        AlpharangeLabel                 matlab.ui.control.Label
        maxEditFieldLabel               matlab.ui.control.Label
        maxEditField                    matlab.ui.control.NumericEditField
        MaxmotorSpeedrpmEditFieldLabel  matlab.ui.control.Label
        MaxmotorSpeedrpmEditField       matlab.ui.control.NumericEditField
        MaxtorquemNmEditFieldLabel      matlab.ui.control.Label
        MaxtorquemNmEditField           matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        ActuatorspeedrpmEditFieldLabel  matlab.ui.control.Label
        ActuatorspeedrpmEditField       matlab.ui.control.NumericEditField
        WormLengthmmEditFieldLabel      matlab.ui.control.Label
        WormLengthmmEditField           matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        kp = 0;
        kod = 0;
        kheight = 0;
        kwidth = 0;
        kalpha = 0:0.2:90;
        kcv = 0;
        speed = 0;
        kscale = [0 90];
        wormLen = 0;
    end


    methods (Access = private)
    
        function outputTorque = motorTorque(app)
            alpha = app.kalpha;
            P = (app.kp)*1e-3;
            od = (app.kod)*1e-2;
            width = (app.kwidth)*1e-2;
            height = (app.kheight)*1e-2;
            cv = app.kcv;
            
            outputTorque = zeros(1,length(alpha));
            
        for i=1:length(alpha)
            Alpha = alpha(i)*pi/180;
            
            beta = atan2((od*sin(Alpha)-height),(od*cos(Alpha)+width));
            
            outputTorque(i) = -P*cv / (2*pi*od*sin(beta-Alpha));
        end
        
            lb = (app.kscale(1)+1) / 0.2;
            lh = (app.kscale(2)) / 0.2;
            app.MaxtorquemNmEditField.Value = max(outputTorque(lb:lh))*1e3;
            
        end
        
        function spd = motorSpeed(app)
            P = (app.kp)*1e-3;
            aDot = app.speed * 2*pi/60;
            od = (app.kod)*1e-2;
            width = (app.kwidth)*1e-2;
            height = (app.kheight)*1e-2;
            alpha = app.kalpha;
            
            spd = zeros(1,length(alpha));
            for i=1:length(alpha)
                Alpha = alpha(i)*pi/180;
                spd(i) = (2*pi / P) *  aDot*od*(width*sin(Alpha)+height*cos(Alpha))/sqrt( height^2 + width^2 +od^2 + 2*od*(width*cos(Alpha)-height*sin(Alpha))) ;
            end            
            
            spd = spd * (60/(2*pi));
           
            lb = (app.kscale(1)+1) / 0.2;
            lh = (app.kscale(2)) / 0.2;
            pdd = spd(lb:lh);
            app.MaxmotorSpeedrpmEditField.Value = max(pdd);
        end
        
        function wormCalc(app)
            lb = (app.kscale(1)+1) / 0.2;
            lh = (app.kscale(2)) / 0.2;
            alpha = (app.kalpha);
            alpha = alpha(lb:lh);
            app.wormLen = max(sqrt((app.kheight)^2 + (app.kwidth)^2 + (app.kod)^2 + 2*app.kod*(app.kwidth*cos(alpha)-app.kheight*sin(alpha))));
            app.WormLengthmmEditField.Value = app.wormLen*10;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.kp = app.pSlider.Value;
            app.kod = app.ODSlider.Value;
            app.kheight = app.heightSlider.Value;
            app.kwidth = app.widthSlider.Value;
            app.kcv = app.CvSlider.Value;
            
            app.pmmroundEditField.Value = num2str(app.kp);
            app.ODcmEditField.Value = num2str(app.kod);
            app.heightcmEditField.Value = num2str(app.kheight);
            app.widthcmEditField.Value = num2str(app.kwidth);
            app.CvNmEditField.Value = num2str(app.kcv);
            
            app.speed = app.ActuatorspeedrpmEditField.Value;
            
            plot(app.UIAxes,app.kalpha,motorTorque(app))
            app.UIAxes.XLim = [0 90];
            
            
            plot(app.UIAxes2,app.kalpha,motorSpeed(app))
            app.UIAxes2.XLim = [0 90];
            
        end

        % Value changed function: pSlider
        function pSliderValueChanged(app, event)
            app.kp = app.pSlider.Value;
            plot(app.UIAxes,app.kalpha,motorTorque(app))
            plot(app.UIAxes2,app.kalpha,motorSpeed(app))
            app.pmmroundEditField.Value = num2str(app.kp);
        end

        % Value changed function: ODSlider
        function ODSliderValueChanged(app, event)
            app.kod = app.ODSlider.Value;
            plot(app.UIAxes,app.kalpha,motorTorque(app))
            plot(app.UIAxes2,app.kalpha,motorSpeed(app))
            app.ODcmEditField.Value = num2str(app.kod);
            wormCalc(app)
        end

        % Value changed function: heightSlider
        function heightSliderValueChanged(app, event)
            app.kheight = app.heightSlider.Value;
            plot(app.UIAxes,app.kalpha,motorTorque(app))
            plot(app.UIAxes2,app.kalpha,motorSpeed(app))
            app.heightcmEditField.Value = num2str(app.kheight);
            wormCalc(app)
        end

        % Value changed function: widthSlider
        function widthSliderValueChanged(app, event)
            app.kwidth = app.widthSlider.Value;
            plot(app.UIAxes,app.kalpha,motorTorque(app))
            plot(app.UIAxes2,app.kalpha,motorSpeed(app))
            app.widthcmEditField.Value = num2str(app.kwidth);
            wormCalc(app)
        end

        % Value changed function: CvSlider
        function CvSliderValueChanged(app, event)
            app.kcv = app.CvSlider.Value;
            plot(app.UIAxes,app.kalpha,motorTorque(app))
            plot(app.UIAxes2,app.kalpha,motorSpeed(app))
            app.CvNmEditField.Value = num2str(app.kcv);
        end

        % Value changed function: minEditField
        function minEditFieldValueChanged(app, event)
            value = app.minEditField.Value;
            value2 = app.maxEditField.Value;
            if(value<value2)
                app.UIAxes.XLim = [value value2];
                app.UIAxes2.XLim = [value value2];
                app.kscale = [value value2];
                plot(app.UIAxes,app.kalpha,motorTorque(app))
                plot(app.UIAxes2,app.kalpha,motorSpeed(app))
            end
        end

        % Value changed function: maxEditField
        function maxEditFieldValueChanged(app, event)

            value = app.minEditField.Value;
            value2 = app.maxEditField.Value;
            if(value<value2)
                app.UIAxes.XLim = [value value2];
                app.UIAxes2.XLim = [value value2];
                app.kscale = [value value2];
                plot(app.UIAxes,app.kalpha,motorTorque(app))
                plot(app.UIAxes2,app.kalpha,motorSpeed(app))
            end
        end

        % Value changed function: ActuatorspeedrpmEditField
        function speedEdit(app, event)
            app.speed = app.ActuatorspeedrpmEditField.Value;
            plot(app.UIAxes2,app.kalpha,motorSpeed(app))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LIactuatortorquecomputeUIFigure = uifigure;
            app.LIactuatortorquecomputeUIFigure.Name = 'LIactuator torque compute';
            app.UIAxes = uiaxes(app.LIactuatortorquecomputeUIFigure);
            title(app.UIAxes, 'Motor torque')
            xlabel(app.UIAxes, 'alpha')
            ylabel(app.UIAxes, 'Torque [N.m]')
            app.pSliderLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.pSliderLabel.Text = 'p';
            app.pSlider = uislider(app.LIactuatortorquecomputeUIFigure);
            app.pSlider.Limits = [0.2 5];
            app.pSlider.MajorTicks = [0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5];
            app.pSlider.MajorTickLabels = {'0.2', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '5'};
            app.pSlider.ValueChangedFcn = createCallbackFcn(app, @pSliderValueChanged, true);
            app.pSlider.Value = 0.2;
            app.ODSliderLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.ODSliderLabel.Text = 'OD';
            app.ODSlider = uislider(app.LIactuatortorquecomputeUIFigure);
            app.ODSlider.Limits = [1 7];
            app.ODSlider.MajorTicks = [1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7];
            app.ODSlider.MajorTickLabels = {'1', '', '', '', '', '', '', '', '', '', '', '', '7'};
            app.ODSlider.ValueChangedFcn = createCallbackFcn(app, @ODSliderValueChanged, true);
            app.ODSlider.Value = 4;
            app.heightSliderLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.heightSliderLabel.Text = 'height';
            app.heightSlider = uislider(app.LIactuatortorquecomputeUIFigure);
            app.heightSlider.Limits = [0.5 5];
            app.heightSlider.MajorTicks = [0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.heightSlider.MajorTickLabels = {'0.5', '', '', '', '', '', '', '', '', '5'};
            app.heightSlider.ValueChangedFcn = createCallbackFcn(app, @heightSliderValueChanged, true);
            app.heightSlider.Value = 1;
            app.widthSliderLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.widthSliderLabel.Text = 'width';
            app.widthSlider = uislider(app.LIactuatortorquecomputeUIFigure);
            app.widthSlider.Limits = [0.5 14];
            app.widthSlider.MajorTickLabels = {'0.5', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '14'};
            app.widthSlider.ValueChangedFcn = createCallbackFcn(app, @widthSliderValueChanged, true);
            app.widthSlider.MinorTicks = [];
            app.widthSlider.Value = 10;
            app.CvSliderLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.CvSliderLabel.Text = 'Cv';
            app.CvSlider = uislider(app.LIactuatortorquecomputeUIFigure);
            app.CvSlider.Limits = [0 4];
            app.CvSlider.ValueChangedFcn = createCallbackFcn(app, @CvSliderValueChanged, true);
            app.CvSlider.Value = 1.8;
            app.minEditFieldLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.LIactuatortorquecomputeUIFigure, 'numeric');
            app.minEditField.Limits = [0 360];
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.CvNmEditFieldLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.CvNmEditFieldLabel.Text = 'Cv [N.m]';
            app.CvNmEditField = uieditfield(app.LIactuatortorquecomputeUIFigure, 'text');
            app.CvNmEditField.Editable = 'off';
            app.ODcmEditFieldLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.ODcmEditFieldLabel.Text = 'OD [cm]';
            app.ODcmEditField = uieditfield(app.LIactuatortorquecomputeUIFigure, 'text');
            app.ODcmEditField.Editable = 'off';
            app.pmmroundEditFieldLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.pmmroundEditFieldLabel.Text = 'p [mm/round]';
            app.pmmroundEditField = uieditfield(app.LIactuatortorquecomputeUIFigure, 'text');
            app.pmmroundEditField.Editable = 'off';
            app.heightcmEditFieldLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.heightcmEditFieldLabel.Text = 'height [cm]';
            app.heightcmEditField = uieditfield(app.LIactuatortorquecomputeUIFigure, 'text');
            app.heightcmEditField.Editable = 'off';
            app.widthcmEditFieldLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.widthcmEditFieldLabel.Text = 'width [cm]';
            app.widthcmEditField = uieditfield(app.LIactuatortorquecomputeUIFigure, 'text');
            app.widthcmEditField.Editable = 'off';
            app.AlpharangeLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.AlpharangeLabel.Text = 'Alpha range';
            app.maxEditFieldLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.LIactuatortorquecomputeUIFigure, 'numeric');
            app.maxEditField.Limits = [0 360];
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @maxEditFieldValueChanged, true);
            app.maxEditField.Value = 90;
            app.MaxmotorSpeedrpmEditFieldLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.MaxmotorSpeedrpmEditFieldLabel.Text = 'Max motor Speed [rpm]';
            app.MaxmotorSpeedrpmEditField = uieditfield(app.LIactuatortorquecomputeUIFigure, 'numeric');
            app.MaxmotorSpeedrpmEditField.ValueDisplayFormat = '%.0f';
            app.MaxtorquemNmEditFieldLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.MaxtorquemNmEditFieldLabel.Text = 'Max torque [mN.m]';
            app.MaxtorquemNmEditField = uieditfield(app.LIactuatortorquecomputeUIFigure, 'numeric');
            app.UIAxes2 = uiaxes(app.LIactuatortorquecomputeUIFigure);
            title(app.UIAxes2, 'Motor speed')
            xlabel(app.UIAxes2, 'alpha')
            ylabel(app.UIAxes2, 'Speed [rpm]')
            app.ActuatorspeedrpmEditFieldLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.ActuatorspeedrpmEditFieldLabel.Text = 'Actuator speed [rpm]';
            app.ActuatorspeedrpmEditField = uieditfield(app.LIactuatortorquecomputeUIFigure, 'numeric');
            app.ActuatorspeedrpmEditField.ValueDisplayFormat = '%.0f';
            app.ActuatorspeedrpmEditField.ValueChangedFcn = createCallbackFcn(app, @speedEdit, true);
            app.ActuatorspeedrpmEditField.Value = 100;
            app.WormLengthmmEditFieldLabel = uilabel(app.LIactuatortorquecomputeUIFigure);
            app.WormLengthmmEditFieldLabel.Text = 'Worm Length [mm]';
            app.WormLengthmmEditField = uieditfield(app.LIactuatortorquecomputeUIFigure, 'numeric');
            app.WormLengthmmEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = toruqeApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LIactuatortorquecomputeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LIactuatortorquecomputeUIFigure)
        end
    end
end